classdef GUInotgooey < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        LoadMap5Button          matlab.ui.control.Button
        LoadMap4Button_3        matlab.ui.control.Button
        DoLocalizeButton        matlab.ui.control.Button
        LoadMap3Button          matlab.ui.control.Button
        RightButton             matlab.ui.control.Button
        LeftButton              matlab.ui.control.Button
        BackButton              matlab.ui.control.Button
        FowrardsButton          matlab.ui.control.Button
        DoMappingButton         matlab.ui.control.Button
        Yaw0000Label            matlab.ui.control.Label
        RightUS0000Label        matlab.ui.control.Label
        LeftUS0000Label         matlab.ui.control.Label
        BackLidar0000Label      matlab.ui.control.Label
        FrontLidar0000Label     matlab.ui.control.Label
        RightLidar0000Label     matlab.ui.control.Label
        LeftLidar0000Label      matlab.ui.control.Label
        NewMapButton            matlab.ui.control.Button
        MoveItButton            matlab.ui.control.Button
        TargetYEditField        matlab.ui.control.NumericEditField
        TargetYEditFieldLabel   matlab.ui.control.Label
        TargetXEditField        matlab.ui.control.NumericEditField
        TargetXEditFieldLabel   matlab.ui.control.Label
        SetPosButton            matlab.ui.control.Button
        CurrentYEditField       matlab.ui.control.NumericEditField
        CurrentYEditFieldLabel  matlab.ui.control.Label
        CurrentXEditField       matlab.ui.control.NumericEditField
        CurrentXEditFieldLabel  matlab.ui.control.Label
        MapLabel                matlab.ui.control.Label
        LoadMap2Button          matlab.ui.control.Button
        LoadMap1Button          matlab.ui.control.Button
        PollSensorsButton       matlab.ui.control.Button
        ReplyLabel              matlab.ui.control.Label
    end


    properties (Access = public)
        client
        map
        xpos
        ypos
        targetx
        targety
        cellstack
    end

    methods (Access = public)
        function setResponse(app, response)
            try
                set(app.ReplyLabel, 'Text', response);
            catch
                %no response
            end
        end
        function setMapDisplay(app, response)
            try
                set(app.MapLabel, 'Text', response);
            catch
                %no response
            end
        end
        %Convert a topological grid to a wallmap
        function wallmap = top2walls(~, tmap)
            w = width(tmap);
            h = height(tmap);
            wallmap = zeros(h + h + 1, w + 1);
            for i = 1:h
                for j = 1:w
                    wallmap(2*i -1, j) = bitor(wallmap(2*i - 1, j), bitshift(bitshift(tmap(i, j), 63), -63)); %up
                    wallmap(2*i, j + 1) = bitor(wallmap(2*i, j + 1), bitshift(bitshift(bitshift(tmap(i, j), -1), 63), -63)); %right
                    wallmap(2*i + 1, j) = bitor(wallmap(2*i + 1, j), bitshift(bitshift(bitshift(tmap(i, j), -2), 63), -63)); %down
                    wallmap(2*i, j) = bitor(wallmap(2*i, j), bitshift(bitshift(bitshift(tmap(i, j), -3), 63), -63)); %left

                end
            end
        end
        %Convert occupancy grid to a wallmap
        function wallmap = opp2walls(~, omap)
            w = width(omap);
            h = height(omap);
            wallmap = zeros(h + h + 1, w + 1);
            for i = 1:h
                for j = 1:w
                    wallmap(2*i -1, j) =(wallmap(2*i - 1, j) || omap(i, j) == 99); %up
                    wallmap(2*i, j + 1) = (wallmap(2*i, j + 1) || omap(i, j) == 99); %right
                    wallmap(2*i + 1, j) = (wallmap(2*i + 1, j) || omap(i, j) == 99); %down
                    wallmap(2*i, j) = (wallmap(2*i, j) || omap(i, j) == 99); %left
                    if i == 1
                        wallmap(2*j, 1) = 1;
                    end
                    if j == 1
                        wallmap(1, i) = 1;
                    end
                    if j == h
                        wallmap(2*j + 1, i) = 1;
                    end
                    if i == w
                        wallmap(2*j, i + 1) = 1;
                    end

                end
            end


        end
        %Print an ASCII art representaion of a wallmpa
        function output = printwallmap(app, wallmap)
            w = width(wallmap);
            h = height(wallmap);
            output = '';
            for i = 1:2:h
                for j = 1:w
                    %Even rows
                    if (i ~= 1 && wallmap(i - 1, j) == 1)
                        output = output + "|";
                    elseif (i ~= 1 && wallmap(i - 1, j) == 0 || i == 1)
                        output = output + " ";
                    else
                        output = output + "?";
                    end
                    %Odd rows
                    if mod(i, 2) == 1

                        if (wallmap(i, j) == 1)
                            if (app.ypos == (i - 1)/2 && app.xpos == j)
                                output = output + "Δ";
                            else
                                output = output + "_";
                            end
                        elseif (wallmap(i, j) == 0)
                            if (app.ypos == (i - 1)/2 && app.xpos == j)
                                output = output + "^";
                            else
                                output = output + " ";
                            end
                        else
                            if (app.ypos == (i - 1)/2 && app.xpos == j)
                                output = output + "&";
                            else
                                output = output + "?";
                            end
                        end
                    end

                end
                output = output + newline;
            end
        end

        %BFS pathplanning
        function nodelist = bfs(app, wallmap, originX, originY, targetx, targety)
            %Seed stuff
            w = width(wallmap) - 1;
            h = (height(wallmap) - 1)/2;
            currentX = originX;
            currentY = originY;
            cellstoExplore = [originX; originY; -999; -999];
            cellsExplored = [-999; -999; -999; -999];
            if originX == targetx && originY == targety %Return early if at position
                nodelist = [originX; originY];
                return;
            else
                %Do the BFS
                while (width(cellstoExplore) > 0)
                    currentX = cellstoExplore(1, 1); %active position
                    currentY = cellstoExplore(2, 1);
                    cellsExplored = [cellsExplored(), cellstoExplore(:, 1)];
                    cellstoExplore = cellstoExplore(:, 2:end);
                    %Check walls, add them if wall is absent and cell is not expored
                    if wallmap(2*currentY -1, currentX) == 0%up
                        if ~app.contains(cellsExplored, currentX, currentY - 1)
                            cellstoExplore(:, end + 1) = [currentX, currentY - 1, currentX, currentY];
                        end
                    end
                    if wallmap(2*currentY, currentX + 1) == 0 %right
                        if ~app.contains(cellsExplored, currentX + 1, currentY)
                            cellstoExplore(:, end + 1) = [currentX + 1, currentY, currentX, currentY];
                        end
                    end
                    if wallmap(2*currentY + 1, currentX) == 0%down
                        if ~app.contains(cellsExplored, currentX, currentY + 1)
                            cellstoExplore(:, end + 1) = [currentX, currentY + 1, currentX, currentY];
                        end
                    end
                    if wallmap(2*currentY, currentX) == 0%left
                        if ~app.contains(cellsExplored, currentX - 1, currentY)
                            cellstoExplore(:, end + 1) = [currentX - 1, currentY, currentX, currentY];
                        end
                    end
                    if app.contains(cellsExplored, targetx, targety) %If active position is target position
                        break;
                    end
                end

                %Recreate path
                path = cellsExplored(1:2, end);
                while true
                    path = [path, retraceRoute(app, cellsExplored, path(:, end))];
                    if (path(1, end) == originX && path(2, end) == originY) %Robot is at current positoin
                        break;
                    end
                end
                nodelist = fliplr(path); %Flip the map such that it ends with the target position

            end
        end

        %Function to support mapping and localizatoin. 
        function tempmap = domapping(app, localize) 
            pause("on")
            tempmap = newMap(app);
            app.xpos = 1;
            app.ypos = 1;
            currentX = app.xpos;
            currentY = app.ypos;

            setMapDisplay(app, printwallmap(app, tempmap)); %Display new map

            while(true) 
                pause(2) %Delay to read sensors
                currentX = app.xpos;
                currentY = app.ypos;
                %Poll sensors
                app.client.write("ps" + newline) %Request sneosr data
                app.client.flush()
                received = app.client.readline();
                setResponse(app, received);
                splitstuff = split(received, ",");
                %fl bl ll rl lus rus yaw
                %Parse data and update GUI
                app.FrontLidar0000Label.Text = "Front Lidar: " + splitstuff(1);
                app.BackLidar0000Label.Text = "Back Lidar: " + splitstuff(2);
                app.LeftLidar0000Label.Text = "Left Lidar: " + splitstuff(3);
                app.RightLidar0000Label.Text = "Right Lidar: " + splitstuff(4);
                app.LeftUS0000Label.Text = "Left USL: " + splitstuff(5);
                app.RightUS0000Label.Text = "Right USL: " + splitstuff(6);
                app.Yaw0000Label.Text = "Yaw: " + splitstuff(7);

                yaw = round(str2double(splitstuff(7)) / (pi/2), 1) * pi/2; %Get current robot angle
                if yaw < 0 
                    yaw = yaw + 2*pi;
                end

                %Check if readings have a wal or not
                sensoroutputs = [str2double(splitstuff(1)) < 35, str2double(splitstuff(4)) < 25, str2double(splitstuff(2)) < 25, str2double(splitstuff(3)) < 25];
                %Rotate sensors to be in the same coordinate frame as the map
                while yaw > .1
                    sensoroutputs = [sensoroutputs(4), sensoroutputs(1:3)];
                    yaw = yaw - pi/2;
                end
                
                %Ordered to be up right down left


                %Add cells to she stack if they are not explored and there is no wall                
                if tempmap(2*currentY -1, currentX) == -1%up
                    tempmap(2*currentY -1, currentX) = max([tempmap(2*currentY - 1, currentX), sensoroutputs(1)]);
                    if ~app.contains(app.cellstack, currentX, currentY - 1) && tempmap(2*currentY -1, currentX) == 0
                        app.cellstack = [[currentX; currentY - 1], app.cellstack];
                    end
                end               
                if tempmap(2*currentY, currentX + 1)== -1 %right
                    tempmap(2*currentY, currentX + 1) = max([tempmap(2*currentY, currentX + 1), sensoroutputs(2)]);
                    if ~app.contains(app.cellstack, currentX + 1, currentY) && tempmap(2*currentY, currentX + 1) == 0
                        app.cellstack = [[currentX + 1; currentY], app.cellstack];
                    end
                end       
                if tempmap(2*currentY + 1, currentX) == -1%down
                    tempmap(2*currentY + 1, currentX) = max([tempmap(2*currentY + 1, currentX),sensoroutputs(3)]);
                    if ~app.contains(app.cellstack, currentX, currentY + 1) && tempmap(2*currentY + 1, currentX) == 0
                        app.cellstack = [[currentX; currentY + 1], app.cellstack];
                    end
                end
                if tempmap(2*currentY, currentX) == -1 %left
                    tempmap(2*currentY, currentX) = max([tempmap(2*currentY, currentX), sensoroutputs(4)]);
                    if ~app.contains(app.cellstack, currentX - 1, currentY) && tempmap(2*currentY, currentX) == 0
                        app.cellstack = [[currentX - 1; currentY], app.cellstack];
                    end
                end

                w = width(tempmap);
                h = height(tempmap);

                minx = min(app.cellstack(1, :));
                maxx = max(app.cellstack(1, :));
                miny = min(app.cellstack(2, :));
                maxy = max(app.cellstack(2, :));

                app.cellstack
                if minx < 1 %need to expand left
                   tempmap = expandMap(app, tempmap,'l');
                end
                if miny < 1 %need to expand up
                   tempmap = expandMap(app,  tempmap,'u');
                end
                if maxx > w - 1 %need to expand right
                   tempmap = expandMap(app, tempmap, 'r');
                end
                if maxy * 2 >= h %need to expand down
                   tempmap = expandMap(app, tempmap, 'd');
                end
                setMapDisplay(app, printwallmap(app, tempmap));

                if isempty(app.cellstack)
                    break
                end

                %Additional logic required to localize
                if localize == true
                    pos = app.checkOverlap(app.map, tempmap); %Check for lverlaps
                    tempxpos = app.xpos;
                    tempypos = app.ypos;
                    if width(pos) == 1 %1 and only pos
                        %Position orrection for rotating the map
                        if pos(3) == 1
                            app.xpos = pos(1) + tempxpos - 1;
                            app.ypos = pos(2) + tempypos - 1;
                        elseif pos(3) == 2
                            app.xpos = pos(1) + tempypos - 1;
                            app.ypos = pos(2) + width(tempmap) - tempxpos - 1;
                        elseif pos(3) == 3
                            app.xpos = pos(1) + width(tempmap) -  tempxpos - 1;
                            app.ypos = pos(2) + (height(tempmap) + 1) / 2 - tempypos - 1;
                        else
                            app.xpos = pos(1) + (height(tempmap) + 1)/2 -  tempypos - 1;
                            app.ypos = pos(2) + tempxpos - 1;
                        end

                        app.xpos
                        app.ypos
                        setMapDisplay(app, printwallmap(app, app.map));
                        app.CurrentXEditField.Value = app.xpos;
                        app.CurrentYEditField.Value = app.ypos;
                        break
                    end
                end

                %Travel to the next cell on the list
                nodelist = app.bfs(tempmap, app.xpos, app.ypos, app.cellstack(1, 1), app.cellstack(2, 1)); %travel there
                for i = 2:width(nodelist)
                    app.client.write("moveto " + ((nodelist(1, i) - app.xpos) * 457.2) + " " + ((nodelist(2, i) - app.ypos) * 457.2) + newline);
                    app.xpos = nodelist(1, i);
                    app.ypos = nodelist(2, i);
                    received = app.client.readline();
                    setResponse(app, received);
                end
                if(isempty(app.cellstack)) %Entire explorable area done
                    break
                end
                app.cellstack(:, 1) = []; %Remove first element after traveling
                %Wait for robot to be at position
                while (true)
                    app.client.write("atpos" + newline)
                    received = app.client.readline();
                    if str2double(received) == 1
                        break;
                    end
                end
                
               
            end
        end

        %Check if a list of cells contains a specific position
        function res = contains(app, cells, targetX, targetY)
            res = false;
            for i = 1:size(cells,2)
                if isequal(cells(1:2,i), [targetX; targetY])
                    res = true;
                    break
                end
            end
        end

        %Retrace linked cells to find the original cell
        function previousCell = retraceRoute(~, cells, currentCell)
            for i = 1:width(cells)
                if cells(1:2, i) == currentCell
                    previousCell = cells(3:4, i);
                    break;
                end
            end
        end

        %Rotate the wallmap datastructure 90 degrees CCW.
        function newMap = rotateWallMap90(app, wallmap)
            newMap = zeros((width(wallmap) - 1) * 2 + 1, (height(wallmap) - 1)/2 + 1);
            %Populate odd rows

            for i = (1:2:height(newMap))
                for j = 1:width(newMap) - 1
                    newMap(i, j) = wallmap(2*j, width(wallmap) + 1 - (i + 1) / 2);
                end
            end
            %Populate even rows
            for i = 2:2:height(newMap)
                for j = 1:width(newMap)
                    newMap(height(newMap) + 1 - i, j) = wallmap(2*j - 1 , i / 2);
                end
            end

        end

        %Check where one map can overlap another, including rotations. Unexplored cells are ignored
        function pos = checkOverlap(app, fullmap, partialmap)
            pos = [];
            wf = width(fullmap);
            hf = height(fullmap);
            tempmap = partialmap;

            breakloops = 0; %Matlab does not support multiple breaks
            for r = 1:4 %All rotations
                printwallmap(app, tempmap)
                wdiff = wf - width(tempmap) + 1;
                hdiff = hf - height(tempmap) + 1;
                wl = width(tempmap);
                hl = height(tempmap);

                for j = 1:2:hdiff %Iterate through map positions
                    for i = 1:wdiff                        
                        for j2 = 1:hl %Iterate through tempmap position
                            for i2 = 1:(wl - mod(j2, 2))
                                if (tempmap(j2, i2) == 1 && ... %Essentially xor but accepts -1 arguments
                                        fullmap(j + j2 - 1, i + i2 - 1) == 0) || ... 
                                    (tempmap(j2, i2) == 0 && ...
                                    fullmap(j + j2 - 1, i + i2 - 1) == 1) || fullmap(j + j2 - 1, i + i2 - 1) == 2
                                    breakloops = 1; %Not the pos
                                end
                            end
                            if breakloops
                                break;
                            end
                        end
                        if ~breakloops %Add overlap to pos vector
                            pos = [pos, [i, (j + 1) / 2, r]'];
                        end
                        breakloops = 0;


                    end
                end
                tempmap = rotateWallMap90(app, tempmap);
            end
                
        end
          
        %Adds a row of unexplored cells to a map. Positions are shifted if the left or top is expanded
        function tempmap = expandMap(app, tempmap, dir)
            w = width(tempmap);
            h = height(tempmap);
            if dir == 'u'
                tempmap = [-1*ones(1, w - 1), 0; -1*ones(1, w); tempmap];
                app.ypos = app.ypos + 1;
                app.cellstack(2, :) = app.cellstack(2, :) + 1;
            elseif dir == 'r'
                tempmap = [tempmap, -1*ones(h, 1)];
                for i = 1:2:h
                    tempmap(i, w) = -1;
                    tempmap(i, w + 1) = 0;
                end
            elseif dir == 'd'
                tempmap = [tempmap; -1*ones(2, w)];
                tempmap(h + 2, w) = 0;
            else
                tempmap = [-1*ones(h, 1), tempmap];
                app.xpos = app.xpos + 1;
                app.cellstack(1, :) = app.cellstack(1, :) + 1;
            end
        end

        %Creates a new map
        function newMap = newMap(app)
            newMap = [-1 0; -1 -1; -1 0];
        end


    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.xpos = 1;
            app.ypos = 1;
            [status,result] = system('arp -a'); %windows command to get a bunch of ip addresses
            pat = regexpPattern("192\.168\.137\.[0-9]+");
            ipAddrArray = extract(result, pat); %use the regex to find 0 or 1 ip addresses


            [r, c]=size(ipAddrArray);

            %if there was an ip address found
            if r==1
                ipAddr = ipAddrArray{1}

                %connect as a client (robot is the server)
                app.client = tcpclient(ipAddr, 23, "Timeout", 5); %to check ip address: right click mobile hotspot, go to settings
                fprintf("got client\n");

                %send a message
                fprintf("sending a message\n");
                app.client.write("amogus");
                app.client.flush()

                received = app.client.readline();
                setResponse(app, received);

                %disconnect from robot

            else %no ip address found
                fprintf("No ip found, check if hotspot is on and robot is on\n");
            end
        end

        % Button pushed function: PollSensorsButton
        function PollSensorsButtonPushed(app, event)
            app.client.write("ps" + newline)
            app.client.flush()
            received = app.client.readline();
            setResponse(app, received);
            splitstuff = split(received, ",");
            %fl bl ll rl lus rus yaw
            app.FrontLidar0000Label.Text = "Front Lidar: " + splitstuff(1);
            app.BackLidar0000Label.Text = "Back Lidar: " + splitstuff(2);
            app.LeftLidar0000Label.Text = "Left Lidar: " + splitstuff(3);
            app.RightLidar0000Label.Text = "Right Lidar: " + splitstuff(4);
            app.LeftUS0000Label.Text = "Left USL: " + splitstuff(5);
            app.RightUS0000Label.Text = "Right USL: " + splitstuff(6);
            app.Yaw0000Label.Text = "Yaw: " + splitstuff(7);

        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app.client)
            delete(app)
        end

        % Button pushed function: LoadMap1Button
        function LoadMap1ButtonPushed(app, event)
            app.map = top2walls(app, [9	5	5	3;	14	15	15	10; 15	9	5	6; 13	6	15	15]);
            setMapDisplay(app, printwallmap(app, app.map));
        end

        % Button pushed function: LoadMap2Button
        function LoadMap2ButtonPushed(app, event)
            app.map = top2walls(app, [11 15 15 11; 8 5 5 2; 10 15 15 10; 14 13 5 6]);
            setMapDisplay(app, printwallmap(app, app.map));
        end

        % Button pushed function: SetPosButton
        function SetPosButtonPushed(app, event)
            app.xpos = app.CurrentXEditField.Value;
            app.ypos = app.CurrentYEditField.Value;
            setMapDisplay(app, printwallmap(app, app.map));
        end

        % Button pushed function: MoveItButton
        function MoveItButtonPushed(app, event)
            pause("on")
            app.targetx = app.TargetXEditField.Value;
            app.targety = app.TargetYEditField.Value;

            nodelist = app.bfs(app.map, app.xpos, app.ypos, app.targetx, app.targety)
            %Excecute BFS results
            for i = 2:width(nodelist)
                app.client.write("moveto " + ((nodelist(1, i) - app.xpos) * 457.2) + " " + ((nodelist(2, i) - app.ypos) * 457.2) + newline);
                app.xpos = nodelist(1, i);
                app.ypos = nodelist(2, i);
                received = app.client.readline();
                setResponse(app, received);
            end
            app.xpos = app.targetx;
            app.ypos = app.targety;
            app.CurrentXEditField.Value = app.xpos;
            app.CurrentYEditField.Value = app.ypos;

            setMapDisplay(app, printwallmap(app, app.map));
        end

        % Button pushed function: NewMapButton
        function NewMapButtonPushed(app, event)
            app.map = newMap(app);
            app.xpos = 1;
            app.ypos = 1;
            setMapDisplay(app, printwallmap(app, app.map));
        end

        % Callback function
        function ExpandmapButtonPushed(app, event)
            app.expandMap('l');
            setMapDisplay(app, printwallmap(app, app.map));
        end

        % Button pushed function: DoMappingButton
        function DoMappingButtonPushed(app, event)
            app.map = domapping(app, false);
        end

        % Button pushed function: FowrardsButton
        function FowrardsButtonPushed(app, event)
            app.client.write("moveto  0 -100");
        end

        % Button pushed function: BackButton
        function BackButtonPushed(app, event)
            app.client.write("moveto  0 100");
        end

        % Button pushed function: RightButton
        function RightButtonPushed(app, event)
            app.client.write("moveto 100 0");
        end

        % Button pushed function: LeftButton
        function LeftButtonPushed(app, event)
            app.client.write("moveto -100 0");
        end

        % Button pushed function: LoadMap3Button
        function LoadMap3ButtonPushed(app, event)
            app.map = opp2walls(app, [0 99 99 0; 0 0 0 0; 0 99 99 0; 0 99 0 0]);
            setMapDisplay(app, printwallmap(app, app.map));
        end

        % Button pushed function: DoLocalizeButton
        function DoLocalizeButtonPushed(app, event)
            domapping(app, true)
        end

        % Callback function: not associated with a component
        function TestOverlapButtonPushed(app, event)
            app.xpos = 2;
            app.ypos = 1;
            tempmap = [1 1 1 0;
                1 0 0 0;
                1 1 0 0;
                2 2 1 1;
                2 2 0 2;];
            tempmap = rotateWallMap90(app, rotateWallMap90(app, rotateWallMap90(app, rotateWallMap90(app, tempmap))))
            setMapDisplay(app, printwallmap(app, app.map));
            pos = app.checkOverlap(app.map, tempmap)
            tempxpos = app.xpos;
            tempypos = app.ypos;
                    if width(pos) == 1 %1 and only pos
                        
                        if pos(3) == 1
                            app.xpos = pos(1) + tempxpos - 1;
                            app.ypos = pos(2) + tempypos - 1;
                        elseif pos(3) == 2
                            app.xpos = pos(1) + tempypos - 1;
                            app.ypos = pos(2) + width(tempmap) - tempxpos - 1;
                        elseif pos(3) == 3
                            app.xpos = pos(1) + width(tempmap) -  tempxpos - 1;
                            app.ypos = pos(2) + (height(tempmap) + 1) / 2 - tempypos - 1;
                        else
                            app.xpos = pos(1) + (height(tempmap) + 1)/2 -  tempypos - 1;
                            app.ypos = pos(2) + tempxpos - 1;
                        end
                        app.xpos
                        app.ypos
                        setMapDisplay(app, printwallmap(app, app.map));
                    end
        end

        % Button pushed function: LoadMap4Button_3
        function LoadMap4Button_3Pushed(app, event)
            app.map = [1 1 1 1 0; ...
                1 0 0 1 1; ...
                1 1 0 1 0; ...
                1 1 1 0 1; ...
                1 1 0 1 0; ...
                1 0 0 1 1; ...
                1 1 0 1 0; ...
                1 1 1 0 1; ...
                1 1 1 1 0;];
            setMapDisplay(app, printwallmap(app, app.map));
        end

        % Button pushed function: LoadMap5Button
        function LoadMap5ButtonPushed(app, event)
            app.map = [1 1 1 1 0;
                    1 1 1 1 1;
                    1 0 1 1 0;
                    1 0 0 1 1;
                    0 0 1 0 0;
                    1 0 0 0 1;
                    1 0 1 0 0;
                    1 1 1 0 1;
                    1 1 1 1 0;];
            setMapDisplay(app, printwallmap(app, app.map));
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.ReplyLabel = uilabel(app.UIFigure);
            app.ReplyLabel.Text = 'Reply';
            app.PollSensorsButton = uibutton(app.UIFigure, 'push');
            app.PollSensorsButton.ButtonPushedFcn = createCallbackFcn(app, @PollSensorsButtonPushed, true);
            app.PollSensorsButton.Text = 'PollSensors';
            app.LoadMap1Button = uibutton(app.UIFigure, 'push');
            app.LoadMap1Button.ButtonPushedFcn = createCallbackFcn(app, @LoadMap1ButtonPushed, true);
            app.LoadMap1Button.Text = 'Load Map 1';
            app.LoadMap2Button = uibutton(app.UIFigure, 'push');
            app.LoadMap2Button.ButtonPushedFcn = createCallbackFcn(app, @LoadMap2ButtonPushed, true);
            app.LoadMap2Button.Text = 'Load Map 2';
            app.MapLabel = uilabel(app.UIFigure);
            app.MapLabel.Text = 'Map';
            app.CurrentXEditFieldLabel = uilabel(app.UIFigure);
            app.CurrentXEditFieldLabel.Text = 'Current X:';
            app.CurrentXEditField = uieditfield(app.UIFigure, 'numeric');
            app.CurrentXEditField.Limits = [1 5];
            app.CurrentXEditField.RoundFractionalValues = 'on';
            app.CurrentXEditField.Value = 1;
            app.CurrentYEditFieldLabel = uilabel(app.UIFigure);
            app.CurrentYEditFieldLabel.Text = 'Current Y:';
            app.CurrentYEditField = uieditfield(app.UIFigure, 'numeric');
            app.CurrentYEditField.Limits = [1 5];
            app.CurrentYEditField.RoundFractionalValues = 'on';
            app.CurrentYEditField.Value = 1;
            app.SetPosButton = uibutton(app.UIFigure, 'push');
            app.SetPosButton.ButtonPushedFcn = createCallbackFcn(app, @SetPosButtonPushed, true);
            app.SetPosButton.Text = 'Set Pos';
            app.TargetXEditFieldLabel = uilabel(app.UIFigure);
            app.TargetXEditFieldLabel.Text = 'Target X:';
            app.TargetXEditField = uieditfield(app.UIFigure, 'numeric');
            app.TargetXEditField.Limits = [1 5];
            app.TargetXEditField.RoundFractionalValues = 'on';
            app.TargetXEditField.Value = 1;
            app.TargetYEditFieldLabel = uilabel(app.UIFigure);
            app.TargetYEditFieldLabel.Text = 'Target Y:';
            app.TargetYEditField = uieditfield(app.UIFigure, 'numeric');
            app.TargetYEditField.Limits = [1 5];
            app.TargetYEditField.RoundFractionalValues = 'on';
            app.TargetYEditField.Value = 1;
            app.MoveItButton = uibutton(app.UIFigure, 'push');
            app.MoveItButton.ButtonPushedFcn = createCallbackFcn(app, @MoveItButtonPushed, true);
            app.MoveItButton.Text = 'MoveIt!';
            app.NewMapButton = uibutton(app.UIFigure, 'push');
            app.NewMapButton.ButtonPushedFcn = createCallbackFcn(app, @NewMapButtonPushed, true);
            app.NewMapButton.Text = 'New Map';
            app.LeftLidar0000Label = uilabel(app.UIFigure);
            app.LeftLidar0000Label.Text = 'Left Lidar: 0.000';
            app.RightLidar0000Label = uilabel(app.UIFigure);
            app.RightLidar0000Label.Text = 'Right Lidar: 0.000';
            app.FrontLidar0000Label = uilabel(app.UIFigure);
            app.FrontLidar0000Label.Text = 'Front Lidar: 0.000';
            app.BackLidar0000Label = uilabel(app.UIFigure);
            app.BackLidar0000Label.Text = 'Back Lidar: 0.000';
            app.LeftUS0000Label = uilabel(app.UIFigure);
            app.LeftUS0000Label.Text = 'Left US: 0.000';
            app.RightUS0000Label = uilabel(app.UIFigure);
            app.RightUS0000Label.Text = 'Right US: 0.000';
            app.Yaw0000Label = uilabel(app.UIFigure);
            app.Yaw0000Label.Text = 'Yaw: 0.000';
            app.DoMappingButton = uibutton(app.UIFigure, 'push');
            app.DoMappingButton.ButtonPushedFcn = createCallbackFcn(app, @DoMappingButtonPushed, true);
            app.DoMappingButton.Text = 'Do Mapping';
            app.FowrardsButton = uibutton(app.UIFigure, 'push');
            app.FowrardsButton.ButtonPushedFcn = createCallbackFcn(app, @FowrardsButtonPushed, true);
            app.FowrardsButton.Text = 'Fowrards';
            app.BackButton = uibutton(app.UIFigure, 'push');
            app.BackButton.ButtonPushedFcn = createCallbackFcn(app, @BackButtonPushed, true);
            app.BackButton.Text = 'Back';
            app.LeftButton = uibutton(app.UIFigure, 'push');
            app.LeftButton.ButtonPushedFcn = createCallbackFcn(app, @LeftButtonPushed, true);
            app.LeftButton.Text = 'Left';
            app.RightButton = uibutton(app.UIFigure, 'push');
            app.RightButton.ButtonPushedFcn = createCallbackFcn(app, @RightButtonPushed, true);
            app.RightButton.Text = 'Right';
            app.LoadMap3Button = uibutton(app.UIFigure, 'push');
            app.LoadMap3Button.ButtonPushedFcn = createCallbackFcn(app, @LoadMap3ButtonPushed, true);
            app.LoadMap3Button.Text = 'Load Map 3';
            app.DoLocalizeButton = uibutton(app.UIFigure, 'push');
            app.DoLocalizeButton.ButtonPushedFcn = createCallbackFcn(app, @DoLocalizeButtonPushed, true);
            app.DoLocalizeButton.Text = 'Do Localize';
            app.LoadMap4Button_3 = uibutton(app.UIFigure, 'push');
            app.LoadMap4Button_3.ButtonPushedFcn = createCallbackFcn(app, @LoadMap4Button_3Pushed, true);
            app.LoadMap4Button_3.Text = 'Load Map 4';
            app.LoadMap5Button = uibutton(app.UIFigure, 'push');
            app.LoadMap5Button.ButtonPushedFcn = createCallbackFcn(app, @LoadMap5ButtonPushed, true);
            app.LoadMap5Button.Text = 'Load Map 5';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUInotgooey

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end